classdef cniQA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        LoadQAdataMenu        matlab.ui.container.Menu
        SaveQAdataMenu        matlab.ui.container.Menu
        QuitMenu              matlab.ui.container.Menu
        FlywheelMenu          matlab.ui.container.Menu
        SigninMenu            matlab.ui.container.Menu
        ProjectlabelMenu      matlab.ui.container.Menu
        SelectdataPanel       matlab.ui.container.Panel
        RetrieveQAButton      matlab.ui.control.Button
        SinceDatePickerLabel  matlab.ui.control.Label
        SinceDatePicker       matlab.ui.control.DatePicker
        CNIImagequalityLabel  matlab.ui.control.Label
        UntilDatePickerLabel  matlab.ui.control.Label
        UntilDatePicker       matlab.ui.control.DatePicker
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cni  =[]; % scitran channel to the cni site
    end
    
    properties (Access = public)
        qaData = [];    % Struct with all the qaData
        qaProject = []; % Flywheel project container
    end


    methods (Access = private)
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(app.cni)
                app.cni = scitran('cni');
            end
            qaProj = app.cni.lookup('cni/qa');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadQAdataMenu = uimenu(app.FileMenu);
            app.LoadQAdataMenu.Text = 'Load QA data';
            app.SaveQAdataMenu = uimenu(app.FileMenu);
            app.SaveQAdataMenu.Text = 'Save QA data';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.FlywheelMenu = uimenu(app.UIFigure);
            app.FlywheelMenu.Text = 'Flywheel';
            app.SigninMenu = uimenu(app.FlywheelMenu);
            app.SigninMenu.Text = 'Sign in';
            app.ProjectlabelMenu = uimenu(app.FlywheelMenu);
            app.ProjectlabelMenu.Text = 'Project label';
            app.SelectdataPanel = uipanel(app.UIFigure);
            app.SelectdataPanel.Title = 'Select data';
            app.RetrieveQAButton = uibutton(app.SelectdataPanel, 'push');
            app.RetrieveQAButton.Text = 'Retrieve QA';
            app.SinceDatePickerLabel = uilabel(app.UIFigure);
            app.SinceDatePickerLabel.Text = 'Since';
            app.SinceDatePicker = uidatepicker(app.UIFigure);
            app.CNIImagequalityLabel = uilabel(app.UIFigure);
            app.CNIImagequalityLabel.Text = 'CNI Image quality';
            app.UntilDatePickerLabel = uilabel(app.UIFigure);
            app.UntilDatePickerLabel.Text = 'Until';
            app.UntilDatePicker = uidatepicker(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Scan Type')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'SNR')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cniQA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end